classdef GroundStationGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LalitkspaceTelemetryUIFigure  matlab.ui.Figure
        BaroLabel                     matlab.ui.control.Label
        GPSLabel                      matlab.ui.control.Label
        ZLabel                        matlab.ui.control.Label
        YLabel                        matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        vLabel_2                      matlab.ui.control.Label
        vLabel                        matlab.ui.control.Label
        TemperatureLamp               matlab.ui.control.Lamp
        LalitkSpaceLabel              matlab.ui.control.Label
        TemperatureGauge              matlab.ui.control.SemicircularGauge
        TemperatureGaugeLabel         matlab.ui.control.Label
        ClearPortButton               matlab.ui.control.StateButton
        Image                         matlab.ui.control.Image
        TelemetryDataPanel            matlab.ui.container.Panel
        fixLamp                       matlab.ui.control.Lamp
        Packets                       matlab.ui.control.NumericEditField
        PacketsLabel                  matlab.ui.control.Label
        ZAxis                         matlab.ui.control.NumericEditField
        ZAxisEditFieldLabel           matlab.ui.control.Label
        YAxis                         matlab.ui.control.NumericEditField
        YAxisEditFieldLabel           matlab.ui.control.Label
        XAxis                         matlab.ui.control.NumericEditField
        XAxisEditFieldLabel           matlab.ui.control.Label
        VoltageLevel_5                matlab.ui.control.NumericEditField
        VLevelLabel                   matlab.ui.control.Label
        VoltageLevel_3                matlab.ui.control.NumericEditField
        VLevelEditFieldLabel          matlab.ui.control.Label
        IMUAltitude                   matlab.ui.control.NumericEditField
        AltitudeEditFieldLabel        matlab.ui.control.Label
        Temperature                   matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel     matlab.ui.control.Label
        LongDir                       matlab.ui.control.EditField
        LatDir                        matlab.ui.control.EditField
        Speed                         matlab.ui.control.NumericEditField
        SpeedEditFieldLabel           matlab.ui.control.Label
        Longitude                     matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel       matlab.ui.control.Label
        Latitude                      matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel        matlab.ui.control.Label
        GPSAltitude                   matlab.ui.control.NumericEditField
        GPSAltitudeEditFieldLabel     matlab.ui.control.Label
        Satellites                    matlab.ui.control.NumericEditField
        GPSSatelliteEditFieldLabel    matlab.ui.control.Label
        Quality                       matlab.ui.control.NumericEditField
        GPSQualityEditFieldLabel      matlab.ui.control.Label
        Fix                           matlab.ui.control.NumericEditField
        GPSFixEditFieldLabel          matlab.ui.control.Label
        StartLamp                     matlab.ui.control.Lamp
        StartStopButton               matlab.ui.control.StateButton
        UIAxes_Altitude               matlab.ui.control.UIAxes
        UIAxes_Ori                    matlab.ui.control.UIAxes
        UIAxes_Voltages               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StartStopButton
        function StartStopButtonValueChanged(app, event)
            value = app.StartStopButton.Value;
                        global rawData;
            if value == 1
%            app.StartLamp.Enable = 'on';
            app.StartLamp.Color = "green";
            delete(instrfindall);
            rawData = serialport("COM10",9600);
            configureTerminator(rawData,"CR/LF");
            %-----------------------------------------------------

            %-----------------------------------------------------
            i = 0;
            while(value == 1)
                flush(rawData);   
                
                data{1} = readline(rawData);
                
                stringData = [data{:}];
                splitData = regexp(stringData,',','split');
                if ((splitData(1) == '<') && (splitData(18) == '>'))
                % Individual Data from String
                gpsFix      = splitData(2);
                gpsQuality  = splitData(3);
                gpsSats     = splitData(4);
                gpsAltitude = splitData(5);
                gpsLat      = splitData(6);
                gpsLong     = splitData(7);
                gpsSpeed    = splitData(8);
                gpsLatDir   = splitData(9);
                gpsLongDir  = splitData(10);
                systemVoltage_5  = splitData(11);
                systemVoltage_3  = splitData(12);
                imuAltitude      = splitData(13);
                imuTemperature   = splitData(14);
                ori_X            = splitData(15);
                ori_Y            = splitData(16);
                ori_Z            = splitData(17);
                i = i + 1;
                end
                
                app.Fix.Value           = str2double(gpsFix);
                app.Quality.Value       = str2double(gpsQuality);
                app.Satellites.Value    = str2double(gpsSats);
                app.GPSAltitude.Value   = str2double(gpsAltitude);
                app.Latitude.Value      = str2double(gpsLat);
                app.Longitude.Value     = str2double(gpsLong);
                app.Speed.Value         = str2double(gpsSpeed);
                app.LatDir.Value              = gpsLatDir;
                app.LongDir.Value             = gpsLongDir;
                app.VoltageLevel_5.Value      = str2double(systemVoltage_5);
                app.VoltageLevel_3.Value      = str2double(systemVoltage_3);
                app.IMUAltitude.Value         = str2double(imuAltitude);
                app.Temperature.Value   = str2double(imuTemperature);
                app.XAxis.Value         = str2double(ori_X);
                app.YAxis.Value         = str2double(ori_Y);
                app.ZAxis.Value         = str2double(ori_Z); 
                app.Packets.Value       = i;
                app.TemperatureGauge.Value = str2double(imuTemperature);
               
                
                v5(i)  = str2double(systemVoltage_5);
                v3(i)  = str2double(systemVoltage_3);
                alti_g(i) = str2double(gpsAltitude);
                alti_i(i) = str2double(imuAltitude);
                X_o(i)    = str2double(ori_X);
                Y_o(i)    = str2double(ori_Y);
                Z_o(i)    = str2double(ori_Z);  
                
                hold(app.UIAxes_Voltages,"on");
                plot(app.UIAxes_Voltages,v3,'g--');
                plot(app.UIAxes_Voltages,v5,'r-');
                hold(app.UIAxes_Voltages,"off");
                
                hold(app.UIAxes_Altitude,"on"); 
                plot(app.UIAxes_Altitude,alti_g,'b-');
                plot(app.UIAxes_Altitude,alti_i,'m:');
                hold(app.UIAxes_Altitude,"off");
                
                hold(app.UIAxes_Ori,"on");
                plot(app.UIAxes_Ori,X_o,'r-');
                plot(app.UIAxes_Ori,Y_o,'g-');
                plot(app.UIAxes_Ori,Z_o,'b-');
                hold(app.UIAxes_Ori,"off");
               
                app.fixLamp.Color = "Red";
                if str2double(gpsFix) == 1
                    app.fixLamp.Color = "Green";
                else
                    app.fixLamp.Color = "Red";
                end
                
                if str2double(imuTemperature) <= 20
                    app.TemperatureLamp.Color = "blue";
                elseif str2double(imuTemperature) > 20 && str2double(imuTemperature) < 24
                    app.TemperatureLamp.Color = "Green";
                elseif str2double(imuTemperature) > 25 && str2double(imuTemperature) < 30
                    app.TemperatureLamp.Color = "Yellow";
                else
                    app.TemperatureLamp.Color = "Red";
                end
                
                clear data
              
                 
            end
            elseif value == 0
%            app.StartLamp.Enable = 'on';
            app.StartLamp.Color = "red";
            rawData = [];
            end
             
        end

        % Value changed function: ClearPortButton
        function ClearPortButtonValueChanged(app, event)
            value = app.ClearPortButton.Value;
            if value == 1
                app.StartLamp.Color = "red";
                clc
                delete(instrfindall)
                clear all
                
            end
        end

        % Callback function
        function StopButtonValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LalitkspaceTelemetryUIFigure = uifigure('Visible', 'off');
            app.LalitkspaceTelemetryUIFigure.Name = 'Lalitk.space Telemetry';
            app.UIAxes_Voltages = uiaxes(app.LalitkspaceTelemetryUIFigure);
            title(app.UIAxes_Voltages, 'Voltages')
            xlabel(app.UIAxes_Voltages, 'packets')
            ylabel(app.UIAxes_Voltages, 'Voltage')
            zlabel(app.UIAxes_Voltages, 'Z')
            app.UIAxes_Voltages.YLim = [0 5.5];
            app.UIAxes_Ori = uiaxes(app.LalitkspaceTelemetryUIFigure);
            title(app.UIAxes_Ori, 'Orientation')
            xlabel(app.UIAxes_Ori, 'packets')
            ylabel(app.UIAxes_Ori, 'Angle')
            zlabel(app.UIAxes_Ori, 'Z')
            app.UIAxes_Altitude = uiaxes(app.LalitkspaceTelemetryUIFigure);
            title(app.UIAxes_Altitude, 'Altitude')
            xlabel(app.UIAxes_Altitude, 'packets')
            ylabel(app.UIAxes_Altitude, 'Meters')
            zlabel(app.UIAxes_Altitude, 'Z')
            app.StartStopButton = uibutton(app.LalitkspaceTelemetryUIFigure, 'state');
            app.StartStopButton.ValueChangedFcn = createCallbackFcn(app, @StartStopButtonValueChanged, true);
            app.StartStopButton.Text = 'Start/Stop';
            app.StartLamp = uilamp(app.LalitkspaceTelemetryUIFigure);
            app.TelemetryDataPanel = uipanel(app.LalitkspaceTelemetryUIFigure);
            app.TelemetryDataPanel.Title = 'Telemetry Data';
            app.GPSFixEditFieldLabel = uilabel(app.TelemetryDataPanel);
            app.GPSFixEditFieldLabel.Text = 'GPS Fix';
            app.Fix = uieditfield(app.TelemetryDataPanel, 'numeric');
            app.GPSQualityEditFieldLabel = uilabel(app.TelemetryDataPanel);
            app.GPSQualityEditFieldLabel.Text = 'GPS Quality';
            app.Quality = uieditfield(app.TelemetryDataPanel, 'numeric');
            app.GPSSatelliteEditFieldLabel = uilabel(app.TelemetryDataPanel);
            app.GPSSatelliteEditFieldLabel.Text = 'GPS Satellite';
            app.Satellites = uieditfield(app.TelemetryDataPanel, 'numeric');
            app.GPSAltitudeEditFieldLabel = uilabel(app.TelemetryDataPanel);
            app.GPSAltitudeEditFieldLabel.Text = 'GPS Altitude';
            app.GPSAltitude = uieditfield(app.TelemetryDataPanel, 'numeric');
            app.LatitudeEditFieldLabel = uilabel(app.TelemetryDataPanel);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.Latitude = uieditfield(app.TelemetryDataPanel, 'numeric');
            app.Latitude.ValueDisplayFormat = '%.3f';
            app.LongitudeEditFieldLabel = uilabel(app.TelemetryDataPanel);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.Longitude = uieditfield(app.TelemetryDataPanel, 'numeric');
            app.Longitude.ValueDisplayFormat = '%.3f';
            app.SpeedEditFieldLabel = uilabel(app.TelemetryDataPanel);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.Speed = uieditfield(app.TelemetryDataPanel, 'numeric');
            app.LatDir = uieditfield(app.TelemetryDataPanel, 'text');
            app.LongDir = uieditfield(app.TelemetryDataPanel, 'text');
            app.TemperatureEditFieldLabel = uilabel(app.TelemetryDataPanel);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.Temperature = uieditfield(app.TelemetryDataPanel, 'numeric');
            app.AltitudeEditFieldLabel = uilabel(app.TelemetryDataPanel);
            app.AltitudeEditFieldLabel.Text = 'Altitude';
            app.IMUAltitude = uieditfield(app.TelemetryDataPanel, 'numeric');
            app.VLevelEditFieldLabel = uilabel(app.TelemetryDataPanel);
            app.VLevelEditFieldLabel.Text = '3V Level';
            app.VoltageLevel_3 = uieditfield(app.TelemetryDataPanel, 'numeric');
            app.VLevelLabel = uilabel(app.TelemetryDataPanel);
            app.VLevelLabel.Text = '5V Level';
            app.VoltageLevel_5 = uieditfield(app.TelemetryDataPanel, 'numeric');
            app.XAxisEditFieldLabel = uilabel(app.TelemetryDataPanel);
            app.XAxisEditFieldLabel.Text = 'X Axis';
            app.XAxis = uieditfield(app.TelemetryDataPanel, 'numeric');
            app.YAxisEditFieldLabel = uilabel(app.TelemetryDataPanel);
            app.YAxisEditFieldLabel.Text = 'Y Axis';
            app.YAxis = uieditfield(app.TelemetryDataPanel, 'numeric');
            app.ZAxisEditFieldLabel = uilabel(app.TelemetryDataPanel);
            app.ZAxisEditFieldLabel.Text = 'Z Axis';
            app.ZAxis = uieditfield(app.TelemetryDataPanel, 'numeric');
            app.PacketsLabel = uilabel(app.TelemetryDataPanel);
            app.PacketsLabel.Text = 'Packets';
            app.Packets = uieditfield(app.TelemetryDataPanel, 'numeric');
            app.fixLamp = uilamp(app.TelemetryDataPanel);
            app.Image = uiimage(app.LalitkspaceTelemetryUIFigure);
            app.Image.ImageSource = 'logo_Lalit_Kumar.png';
            app.ClearPortButton = uibutton(app.LalitkspaceTelemetryUIFigure, 'state');
            app.ClearPortButton.ValueChangedFcn = createCallbackFcn(app, @ClearPortButtonValueChanged, true);
            app.ClearPortButton.Text = 'Clear Port';
            app.TemperatureGaugeLabel = uilabel(app.LalitkspaceTelemetryUIFigure);
            app.TemperatureGaugeLabel.Text = 'Temperature';
            app.TemperatureGauge = uigauge(app.LalitkspaceTelemetryUIFigure, 'semicircular');
            app.TemperatureGauge.Limits = [0 50];
            app.LalitkSpaceLabel = uilabel(app.LalitkspaceTelemetryUIFigure);
            app.LalitkSpaceLabel.Text = 'Lalitk.Space';
            app.TemperatureLamp = uilamp(app.LalitkspaceTelemetryUIFigure);
            app.vLabel = uilabel(app.LalitkspaceTelemetryUIFigure);
            app.vLabel.Text = '5v';
            app.vLabel_2 = uilabel(app.LalitkspaceTelemetryUIFigure);
            app.vLabel_2.Text = '3v';
            app.XLabel = uilabel(app.LalitkspaceTelemetryUIFigure);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.LalitkspaceTelemetryUIFigure);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.LalitkspaceTelemetryUIFigure);
            app.ZLabel.Text = 'Z';
            app.GPSLabel = uilabel(app.LalitkspaceTelemetryUIFigure);
            app.GPSLabel.Text = 'GPS';
            app.BaroLabel = uilabel(app.LalitkspaceTelemetryUIFigure);
            app.BaroLabel.Text = 'Baro';
            % Show the figure after all components are created
            app.LalitkspaceTelemetryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GroundStationGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LalitkspaceTelemetryUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LalitkspaceTelemetryUIFigure)
        end
    end
end